classdef GraphingCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SaveGraphButton_2       matlab.ui.control.Button
        DeleteGraphButton_2     matlab.ui.control.Button
        LinePropertiesButton_2  matlab.ui.control.Button
        PlotDataButton_2        matlab.ui.control.Button
        DotButton_2             matlab.ui.control.Button
        CircleButton_2          matlab.ui.control.Button
        LineButton_2            matlab.ui.control.Button
        GraphFunctionButton_2   matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selectedGraph
        originalProps
    end
    
    methods (Access = private)
        
        function selectGraph(app,src,~)
            if ~isempty(app.selectedGraph) && isvalid(app.selectedGraph)
                % Restore previous graph's properties
                app.selectedGraph.Color = app.originalProps.Color;
                app.selectedGraph.LineWidth = app.originalProps.LineWidth;
            end
            
            % Save current graph's properties
            app.selectedGraph = src;
            app.originalProps.Color = src.Color;
            app.originalProps.LineWidth = src.LineWidth;
            
            % Highlight the selected graph
            src.Color = 'm';
            src.LineWidth = 3;
            end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, [0 10 0 10]);
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Enhanced Graphing Calculator');
            xlabel(app.UIAxes, 'X-axis');
            ylabel(app.UIAxes, 'Y-axis');
            hold(app.UIAxes, 'on');  
            app.UIAxes.UserData = struct('line', [], 'circle', [], 'dot', [], 'function', [], 'plot', []);
        end

        % Button pushed function: LineButton_2
        function LineButton_2Pushed(app, event)
            prompt = {'Enter x1:', 'Enter y1:', 'Enter x2:', 'Enter y2:'};
            dlgtitle = 'Create Line';
            dims = [1 35];
            definput = {'0', '0', '1', '1'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            if ~isempty(answer)
                x1 = str2double(answer{1});
                y1 = str2double(answer{2});
                x2 = str2double(answer{3});
                y2 = str2double(answer{4});
                userData = app.UIAxes.UserData;
                newLine = plot(app.UIAxes, [x1, x2], [y1, y2], 'b-', 'LineWidth', 2, 'ButtonDownFcn', @(src,event)selectGraph(app, src, event));
                userData.line = [userData.line, newLine];
                app.UIAxes.UserData = userData;
                grid(app.UIAxes, 'on'); 
                axis(app.UIAxes, 'tight');
            end
        end

        % Button pushed function: CircleButton_2
        function CircleButton_2Pushed(app, event)
            prompt = {'Enter center x:', 'Enter center y:', 'Enter radius:'};
            dlgtitle = 'Create Circle';
            dims = [1 35];
            definput = {'1', '1', '1'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            if ~isempty(answer)
                centerX = str2double(answer{1});
                centerY = str2double(answer{2});
                radius = str2double(answer{3});
                theta = linspace(0, 2*pi, 100);
                x = centerX + radius * cos(theta);
                y = centerY + radius * sin(theta);
                userData = app.UIAxes.UserData;
                newCircle = plot(app.UIAxes, x, y, 'r-', 'LineWidth', 2, 'ButtonDownFcn', @(src,event)selectGraph(app, src, event));
                userData.circle = [userData.circle, newCircle];
                app.UIAxes.UserData = userData;
                grid(app.UIAxes, 'on'); 
                axis(app.UIAxes, 'tight');
            end
        end

        % Button pushed function: DeleteGraphButton_2
        function DeleteGraphButton_2Pushed(app, event)
            disp('Delete Button Pressed');
            if isempty(app.selectedGraph) || ~isvalid(app.selectedGraph)
                errordlg('No graph selected. Please select a graph to delete.', 'Error');
                return;
            end
            
            delete(app.selectedGraph);
            app.selectedGraph = [];
        end

        % Button pushed function: LinePropertiesButton_2
        function LinePropertiesButton_2Pushed(app, event)
            disp('Line Properties Button Pressed');
            if isempty(app.selectedGraph) || ~isvalid(app.selectedGraph)
                errordlg('No graph selected. Please select a graph to change its properties.', 'Error');
                return;
            end
            
            % Create the dialog window
            d = dialog('Position', [300, 300, 300, 300], 'Name', 'Line Properties');
            
            % Dropdown menu for color selection
            uicontrol('Parent', d, 'Style', 'text', 'Position', [20, 230, 100, 20], 'String', 'Select color:');
            colorCodes = {'r', 'b', 'k', 'g', 'c', 'y', 'm', 'w'};
            colors = {'Red', 'Blue', 'Black', 'Green', 'Cyan', 'Yellow', 'Magenta', 'White'};
            colorDropdown = uicontrol('Parent', d, 'Style', 'popup', 'Position', [120, 230, 100, 20], 'String', colors);
            
            % Input field for line width
            uicontrol('Parent', d, 'Style', 'text', 'Position', [20, 180, 100, 20], 'String', 'Enter line width:');
            lineWidthField = uicontrol('Parent', d, 'Style', 'edit', 'Position', [120, 180, 100, 20], 'String', num2str(app.selectedGraph.LineWidth));
            
            % Dropdown menu for Line type
            uicontrol('Parent', d, 'Style', 'text', 'Position', [20, 130, 100, 20], 'String', 'Select line type:');
            types = {'-', '-.', '--', ':'};
            typesDropdown = uicontrol('Parent', d, 'Style', 'popup', 'Position', [120, 130, 100, 20], 'String', types);
            
            % OK and Cancel button
            uicontrol('Parent', d, 'Position', [50, 20, 70, 25], 'String', 'OK', ...
                'Callback', @(src, event) applyLineProperties(app, colorDropdown, colorCodes, lineWidthField, typesDropdown, d));
            uicontrol('Parent', d, 'Position', [150, 20, 70, 25], 'String', 'Cancel', ...
                'Callback', 'delete(gcf)');
        end
        
        % Function to apply the line properties
        function applyLineProperties(app, colorDropdown, colorCodes, lineWidthField, typesDropdown, dialogHandle)
            if isempty(app.selectedGraph) || ~isvalid(app.selectedGraph)
                errordlg('No graph selected. Please select a graph to change its properties.', 'Error');
                delete(dialogHandle);
                return;
            end
            
            selectedColor = colorCodes{get(colorDropdown, 'Value')};
            newLineWidth = str2double(get(lineWidthField, 'String'));
            selectedLineType = get(typesDropdown, 'String');
            lineType = selectedLineType{get(typesDropdown, 'Value')};
            
            % Update properties
            if ~isnan(newLineWidth) && isfinite(newLineWidth)
                set(app.selectedGraph, 'Color', selectedColor, 'LineWidth', newLineWidth, 'LineStyle', lineType);
                app.originalProps.Color = selectedColor;
                app.originalProps.LineWidth = newLineWidth;
                app.originalProps.LineStyle = lineType;
            else
                errordlg('Invalid line width. Please enter a valid numeric value.', 'Error');
            end
            
            delete(dialogHandle);
        end

        % Button pushed function: DotButton_2
        function DotButton_2Pushed(app, event)
            prompt = {'Enter x:', 'Enter y:'};
            dlgtitle = 'Create Dot';
            dims = [1 35];
            definput = {'0', '0'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            if ~isempty(answer)
                x = str2double(answer{1});
                y = str2double(answer{2});
                userData = app.UIAxes.UserData;
                newDot = plot(app.UIAxes, x, y, 'ko', 'MarkerSize', 10, 'MarkerFaceColor', 'k', 'ButtonDownFcn', @(src,event)selectGraph(app, src, event));
                userData.dot = [userData.dot, newDot];
                app.UIAxes.UserData = userData;
                grid(app.UIAxes, 'on');
                axis(app.UIAxes, 'tight');
            end
        end

        % Button pushed function: GraphFunctionButton_2
        function GraphFunctionButton_2Pushed(app, event)
            prompt = {'Enter function of x (e.g., sin(x), x.^2, 3*x.^3 + 2*x.^2 + x + 5, etc.) note: use .^ for exponents and use * when multiplying:', 'Enter x-range start:', 'Enter x-range end:'};
            dlgtitle = 'Graph Function';
            dims = [1 50];
            definput = {'sin(x)', '0', '10'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            if ~isempty(answer)
                func = answer{1};
                x_start = str2double(answer{2});
                x_end = str2double(answer{3});
                x = linspace(x_start, x_end, 1000);
                
                try
                    f = str2func(['@(x) ' func]);
                    y = f(x);
                    
                    if isequal(size(x), size(y))
                        userData = app.UIAxes.UserData;
                        newFunction = plot(app.UIAxes, x, y, 'g-', 'LineWidth', 2, 'ButtonDownFcn', @(src,event)selectGraph(app, src, event));
                        userData.function = [userData.function, newFunction];
                        app.UIAxes.UserData = userData;
                        grid(app.UIAxes, 'on'); 
                        axis(app.UIAxes, 'tight');
                    else
                        errordlg('Function evaluation failed. Ensure that the function is valid and returns values of appropriate size.', 'Error');
                    end
                catch ME
                    errordlg(['Invalid function. Please enter a valid function of x.', newline, 'Error message: ', ME.message], 'Error');
                end
            end
        end

        % Button pushed function: PlotDataButton_2
        function PlotDataButton_2Pushed(app, event)
            % Create the dialog window
            d = dialog('Position', [300, 300, 300, 200], 'Name', 'Data Plotter');
            
            % Input field for data
            uicontrol('Parent', d, 'Style', 'text', 'Position', [20, 150, 260, 20], 'String', 'Enter data as [x1 y1; x2 y2; ...]:');
            dataField = uicontrol('Parent', d, 'Style', 'edit', 'Position', [20, 120, 260, 20]);
        
            % OK and Cancel button
            uicontrol('Parent', d, 'Position', [50, 20, 70, 25], 'String', 'OK', ...
                'Callback', @(src, event) applyDataPlot(app, dataField, d));
            uicontrol('Parent', d, 'Position', [150, 20, 70, 25], 'String', 'Cancel', ...
                'Callback', 'delete(gcf)');
        end
        
        function applyDataPlot(app, dataField, dialogHandle)
            dataStr = get(dataField, 'String');
            data = str2num(dataStr); %#ok<ST2NM>
            
            if ~isempty(data) && size(data, 2) == 2
                userData = app.UIAxes.UserData;
                newPlot = plot(app.UIAxes, data(:,1), data(:,2), 'ko-', 'LineWidth', 2, 'MarkerSize', 8, 'ButtonDownFcn', @(src,event)selectGraph(app, src, event));
                userData.plot = [userData.plot, newPlot];
                app.UIAxes.UserData = userData;
                grid(app.UIAxes, 'on');
                axis(app.UIAxes, 'tight');
            else
                errordlg('Invalid data. Please enter data as [x1 y1; x2 y2; ...]', 'Error');
            end
            
            delete(dialogHandle);
        end

        % Button pushed function: SaveGraphButton_2
        function SaveGraphButton_2Pushed(app, event)
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.tif'; '*.pdf'}, 'Save Graph As', 'graph.png');
            if ischar(file)
                fullFileName = fullfile(path, file);
                exportgraphics(app.UIAxes, fullFileName);
                msgbox('Graph saved successfully.', 'Success');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Enhanced Graphing Calculator')
            xlabel(app.UIAxes, 'X-axis')
            ylabel(app.UIAxes, 'Y-axis')
            zlabel(app.UIAxes, 'Z')
            app.GraphFunctionButton_2 = uibutton(app.UIFigure, 'push');
            app.GraphFunctionButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraphFunctionButton_2Pushed, true);
            app.GraphFunctionButton_2.Text = 'Graph Function';
            app.LineButton_2 = uibutton(app.UIFigure, 'push');
            app.LineButton_2.ButtonPushedFcn = createCallbackFcn(app, @LineButton_2Pushed, true);
            app.LineButton_2.Text = 'Line';
            app.CircleButton_2 = uibutton(app.UIFigure, 'push');
            app.CircleButton_2.ButtonPushedFcn = createCallbackFcn(app, @CircleButton_2Pushed, true);
            app.CircleButton_2.Text = 'Circle';
            app.DotButton_2 = uibutton(app.UIFigure, 'push');
            app.DotButton_2.ButtonPushedFcn = createCallbackFcn(app, @DotButton_2Pushed, true);
            app.DotButton_2.Text = 'Dot';
            app.PlotDataButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButton_2Pushed, true);
            app.PlotDataButton_2.Text = 'Plot Data';
            app.LinePropertiesButton_2 = uibutton(app.UIFigure, 'push');
            app.LinePropertiesButton_2.ButtonPushedFcn = createCallbackFcn(app, @LinePropertiesButton_2Pushed, true);
            app.LinePropertiesButton_2.Text = 'Line Properties';
            app.DeleteGraphButton_2 = uibutton(app.UIFigure, 'push');
            app.DeleteGraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteGraphButton_2Pushed, true);
            app.DeleteGraphButton_2.Text = 'Delete Graph';
            app.SaveGraphButton_2 = uibutton(app.UIFigure, 'push');
            app.SaveGraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButton_2Pushed, true);
            app.SaveGraphButton_2.Text = 'Save Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphingCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end